classdef defocustracker_ui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        LeftPanel                       matlab.ui.container.Panel
        TabGroup                        matlab.ui.container.TabGroup
        TrainProcessTab                 matlab.ui.container.Tab
        RemoveSelectedJobsButton        matlab.ui.control.Button
        JobsListBox                     matlab.ui.control.ListBox
        JobsListBoxLabel                matlab.ui.control.Label
        ProcessJobsButton               matlab.ui.control.Button
        MovetoJobsButton                matlab.ui.control.Button
        SmoothingEditField              matlab.ui.control.NumericEditField
        SmoothingEditFieldLabel         matlab.ui.control.Label
        MedianFilterEditField           matlab.ui.control.NumericEditField
        MedianFilterEditFieldLabel      matlab.ui.control.Label
        GaussFilterEditField            matlab.ui.control.NumericEditField
        GaussFilterEditFieldLabel       matlab.ui.control.Label
        FoundZDataEditField             matlab.ui.control.EditField
        FoundZDataEditFieldLabel        matlab.ui.control.Label
        LastFrameZ_stackEditField       matlab.ui.control.NumericEditField
        LastFrameZ_stackEditFieldLabel  matlab.ui.control.Label
        FirstFrameZ_stackEditField      matlab.ui.control.NumericEditField
        FirstFrameZ_stackEditFieldLabel  matlab.ui.control.Label
        FoundExperimtalDataTextArea     matlab.ui.control.TextArea
        FoundExperimtalDataTextAreaLabel  matlab.ui.control.Label
        CurrentFrameEditField           matlab.ui.control.NumericEditField
        CurrentFrameEditFieldLabel      matlab.ui.control.Label
        Slider                          matlab.ui.control.Slider
        SliderLabel                     matlab.ui.control.Label
        DemoModeCheckBox                matlab.ui.control.CheckBox
        MedianFilterCheckBox            matlab.ui.control.CheckBox
        ProcessButton                   matlab.ui.control.Button
        TrainButton                     matlab.ui.control.Button
        LoadDataButton                  matlab.ui.control.Button
        LoadZstackDataButton            matlab.ui.control.Button
        LoadPostprocessandViewdataTab   matlab.ui.container.Tab
        ScaledtEditField                matlab.ui.control.NumericEditField
        ScaledtEditFieldLabel           matlab.ui.control.Label
        ScaleUnitEditField              matlab.ui.control.EditField
        ScaleUnitEditFieldLabel         matlab.ui.control.Label
        ScaleZEditField                 matlab.ui.control.NumericEditField
        ScaleZEditFieldLabel            matlab.ui.control.Label
        ScaleYEditField                 matlab.ui.control.NumericEditField
        ScaleYEditFieldLabel            matlab.ui.control.Label
        ScaleXEditField                 matlab.ui.control.NumericEditField
        ScaleXEditFieldLabel            matlab.ui.control.Label
        ScaleCheckBox                   matlab.ui.control.CheckBox
        NameofnewdescriptorEditField    matlab.ui.control.EditField
        NameofnewdescriptorEditFieldLabel  matlab.ui.control.Label
        ListBox_Processed               matlab.ui.control.ListBox
        NameofnewvariableEditField      matlab.ui.control.EditField
        NameofnewvariableEditFieldLabel  matlab.ui.control.Label
        saveprocesseddatatoworkspaceButton  matlab.ui.control.Button
        CloseOldFigureCheckBox          matlab.ui.control.CheckBox
        DZminEditField                  matlab.ui.control.NumericEditField
        DZminEditFieldLabel             matlab.ui.control.Label
        DZmaxEditField                  matlab.ui.control.NumericEditField
        DZmaxEditFieldLabel             matlab.ui.control.Label
        DYmaxEditField                  matlab.ui.control.NumericEditField
        DYmaxEditFieldLabel             matlab.ui.control.Label
        DYminEditField                  matlab.ui.control.NumericEditField
        DYminEditFieldLabel             matlab.ui.control.Label
        DXminEditField                  matlab.ui.control.NumericEditField
        DXminEditFieldLabel             matlab.ui.control.Label
        DXmaxEditField                  matlab.ui.control.NumericEditField
        DXmaxEditFieldLabel             matlab.ui.control.Label
        MinCMEditField                  matlab.ui.control.NumericEditField
        MinCMEditFieldLabel             matlab.ui.control.Label
        MinCMCheckBox                   matlab.ui.control.CheckBox
        TrackingCheckBox                matlab.ui.control.CheckBox
        PostProcessButton               matlab.ui.control.Button
        NameofDescriptorEditField       matlab.ui.control.EditField
        NameofDescriptorEditFieldLabel  matlab.ui.control.Label
        NameofVariableEditField         matlab.ui.control.EditField
        NameofVariableEditFieldLabel    matlab.ui.control.Label
        LoadDataFromWorkspaceButton     matlab.ui.control.Button
        Plot3dTracksButton              matlab.ui.control.Button
        ListBox_Loaded                  matlab.ui.control.ListBox
        LogTab                          matlab.ui.container.Tab
        LogTextArea                     matlab.ui.control.TextArea
        LogTextAreaLabel                matlab.ui.control.Label
    end

    
    properties (Access = private)
        zstack_fullpath         cell %cell array with full path to zstack images
        zstack_folder           char %cell array with folder path to zstack images
        zstack_files            cell %cell array with just filenames to zstack images
        experiment_folders      cell %cell array with just filenames to zstack images
        experiment_base_folder  char
        save_filename           cell
        roi     
        roi_pos                     %property used to recreate the roi, ROI obj gets deleted when a new images is drawn using imshow
        fig_zstack                  %handle for the figure where the ROI is selected
        fig_zstack_zoom             %handle for the figure where the ROI is shown
        fig_train
        fig_model
        ax_roi
        ax_im                       
        ax_crop                     
        im
        model
        d_helper
        debugmode
        logger
        app_dir                     % the directory where mlapp is located
        data
        loaded_data
        post_processed_data
        jobs
        
    end
    
    methods (Access = private)       
        function [] = update_image(app, idx)            
            I = imread(app.zstack_fullpath{idx});
            I= imadjust(I);
            app.im=I;
            if ~check_if_figures_exist(app);
                open_figures(app)
            end
            imshow(I,"Parent", app.ax_im);
            update_roi(app)                              
            update_cropped_image(app)                            
        end

        function [] = update_cropped_image(app)
            if ~check_if_figures_exist(app);                           
                app.fig_zstack_zoom=figure("Name", "cropped image");            
                app.ax_crop=axes('Parent',app.fig_zstack_zoom,'Units','normalized');
            end
            position=app.roi.Position;
            I2=imcrop(app.im,position);             
            imshow(I2,"Parent", app.ax_crop);  
        end

        
        function [] = update_roi(app)
            app.roi=images.roi.Rectangle(app.ax_im,'Position',app.roi_pos);
            addlistener(app.roi,'MovingROI',@app.roi_listener);
            addlistener(app.roi,'ROIMoved',@app.roi_listener);
            
        end
        
        function [] = roi_listener(app,src,evt)
            evname = evt.EventName;
                 switch(evname)
%                     case{'MovingROI'}
%                         disp(['ROI moving Previous Position: ' mat2str(evt.PreviousPosition)]);
%                         disp(['ROI moving Current Position: ' mat2str(evt.CurrentPosition)]);
                    case{'ROIMoved'}
                        %disp(['ROI moved Previous Position: ' mat2str(evt.PreviousPosition)]);
                        %disp(['ROI moved Current Position: ' mat2str(evt.CurrentPosition)]);
                        app.roi_pos=evt.CurrentPosition;
                        update_cropped_image(app);
                 end   
        end
                        
        function [] = train(app) %ouputs data to matlab workspace
            images=app.zstack_files;
            myfolder=app.zstack_folder;
            first_frame=round(app.FirstFrameZ_stackEditField.Value);
            last_frame=round(app.LastFrameZ_stackEditField .Value);
            images=images(first_frame:last_frame);
            img_train = dtracker_create('imageset',myfolder, images);
            img_train.n_frames=numel(images);
            position=app.roi.Position;
            N_cal = img_train.n_frames;
            dat_train = dtracker_create('dataset',N_cal);
            dat_train.fr(:) = 1:N_cal;
            dat_train.X(:) = zeros(1,N_cal) + position(1)+position(3)/2; %350 is the X-coord. of the selected particle
            dat_train.Y(:) = zeros(1,N_cal) + position(2)+position(4)/2; %855 is the Y-coord. of the selected particle
            dat_train.Z(:) = linspace(0,1,N_cal);
            
            %  Create the model with default parameters
            loc_model = dtracker_create('model');       
            %  Edit the relevant field in training parameters
            loc_model.training.imwidth = position(3);            % width of the particle image
            loc_model.training.imheight =  position(4);           % height of the particle image
            loc_model.training.median_filter = app.MedianFilterEditField.Value;       % median filter to reduce thermal noise
            loc_model.training.gauss_filter=app.GaussFilterEditField.Value;
            loc_model.training.smoothing = app.SmoothingEditField.Value;         % smoothing (optional)
            
            %model_wte2.training.n_interp_images = 100;   % interpolation (optional)
            %  Train the *model*
            loc_model = dtracker_train(loc_model,img_train,dat_train); 
            loc_model.identifier=myfolder;
            app.model=loc_model;
            %  Inspect the *model*           
            
            app.close_dtrackerfigure() %closes previously open dtracker igures
            dtracker_show(img_train,dat_train)
            dtracker_show(loc_model)
            %assignin("base", "model", model_wte2)
        end
        
        function  [] = process(app, model,descriptor)            
            data={}; %#ok<ADPROPLC> 
            imagesets={};            
            for i=1 :numel(descriptor)                
                if app.MedianFilterCheckBox.Value==1
                    median_filtered_images = app.d_helper.median(descriptor{i});
                    img_exp = dtracker_create('preloaded_imageset',median_filtered_images);
                    imagesets{i}= dtracker_create('imageset',descriptor{i});  
                else                
                    img_exp = dtracker_create('imageset',descriptor{i}); 
                    imagesets{i}= dtracker_create('imageset',descriptor{i});
                end                        
                app.add_to_log(['Processing ' num2str(i) 'of ' num2str(numel(descriptor)) ' experiments']);
                drawnow;                
                dat_single = dtracker_process(model, img_exp);                            
                data{end+1}=dat_single;                
            end           
            app.data=data;

            assignin("base", "model", model)
            assignin("base", "data", data) %#ok<ADPROPLC> 
            assignin("base", "descriptor", descriptor)
            assignin("base", "imagesets", imagesets)

            app.add_to_log(['Processing complete (' num2str(numel(descriptor)) ' experiments)']);
            wheretosave=fullfile(app.experiment_base_folder, app.save_filename{1});
            i=0;
            prev_wheretosave=wheretosave;
            while isfile(wheretosave)
                i=i+1;                
                [filepath,name,ext] = fileparts(prev_wheretosave);
                name=[name num2str(i)];
                wheretosave=fullfile(filepath,name);                              
            end
            save(wheretosave,"model", "data", "descriptor");
            app.data=data; %#ok<ADPROPLC> 
        end
        
        function [] = close_dtrackerfigure(app) % this function closes some figures created by dtracker. I did not want to refactor dtracker to much
            h=findobj('Type','figure');
            for i=1: numel(h)
                if strcmp(h(i).Name,'dataset: dat_train / imageset: img_train')
                    close(h(i))                
                elseif strcmp(h(i).Name,'model: model_wte2')
                    close(h(i))                
                elseif strcmp(h(i).Name,'3d_tracks')
                    close(h(i))
                end     
            end
        end
        
        function [] = add_to_log(app,to_add)
            to_add= [datestr(datetime) '    ' to_add];            
            app.logger=[{to_add} app.logger];
            app.LogTextArea.Value=app.logger;            
        end
        
        function [] = postprocess(app)
                %  Add the Scaling
                d_scale = dtracker_create('scaling');
                d_scale.X_to_x = app.ScaleXEditField.Value; 
                d_scale.Y_to_y = app.ScaleYEditField.Value; 
                
                d_scale.Z_to_z = app.ScaleZEditField.Value;
                d_scale.dt = app.ScaledtEditField.Value; 
                d_scale.unit = app.ScaleUnitEditField.Value;

                %  Add the tracking
                tracking = dtracker_create('tracking');
                tracking.tracking_step = 1;
                track_box = ...
                [app.DXminEditField.Value ...                
                app.DXmaxEditField.Value ...
                app.DYminEditField.Value ...
                app.DYmaxEditField.Value ...
                app.DZminEditField.Value ...
                app.DZmaxEditField.Value] ;                
                tracking.bounding_box = track_box; %  [DXmin DXmax DYmin DYmax DZmin DZmax]
                app.model.tracking = tracking;
                min_cm=app.MinCMEditField.Value;                
                
                [~,idx] = ismember(app.ListBox_Loaded.Value,app.ListBox_Loaded.Items);
                loc_data=app.loaded_data(idx);
                wb=waitbar(0,'postprocessing')
                for i=1 :numel(loc_data)
                    waitbar(i/numel(loc_data),wb);
                    if app.MinCMCheckBox.Value==1 
                        loc_data{i}=dtracker_postprocess('min_cm',loc_data{i}, min_cm);
                    end
                    if app.ScaleCheckBox.Value==1 %scale data?
                        loc_data{i}=dtracker_postprocess('scale',loc_data{i}, d_scale);
                    end
                    if app.TrackingCheckBox.Value==1
                        loc_data{i}=dtracker_postprocess(tracking, loc_data{i});
                    end
                                        
                end
                app.post_processed_data=loc_data;
                %app.ListBox_Processed.ItemsData=data;
                app.ListBox_Processed.Items=app.ListBox_Loaded.Value;
                close(wb)
        end
        
        
        function [] = process_jobs(app)
            disp(numel(app.jobs.model))
            for i=1 : numel(app.jobs.model)              
                app.process(app.jobs.model{i}, app.jobs.path{i})                
            end
        end
        
        function [] = open_figures(app)
            close all
            app.fig_zstack=figure("Name", "full image");
            app.fig_zstack_zoom=figure("Name", "cropped image");
            app.ax_im=axes('Parent',app.fig_zstack,'Units','normalized');
            app.ax_crop=axes('Parent',app.fig_zstack_zoom,'Units','normalized');
            
        end
        
        function exists = check_if_figures_exist(app) % this function checks if the figures from open_figures exists. In case the user closes them
            h=findobj('Type','figure');
            cropped_exists=false;
            full_exists=false;
            for i=1: numel(h)              
                if strcmp(h(i).Name,"full image")
                    full_exists=true;
                    break
                else
                    full_exists=false;
                end
            end
            for i=1: numel(h) 
                if strcmp(h(i).Name,'cropped image') 
                    cropped_exists=true;
                    break
                else
                    cropped_exists=false;
                end     
            end
            exists=cropped_exists & full_exists;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            close all
            [filepath,~,~] = fileparts(mfilename('fullpath'));
            app.app_dir=filepath;
            addpath(genpath(filepath))
            app.open_figures();
%             app.fig_zstack=figure("Name", "full image");
%             app.fig_zstack_zoom=figure("Name", "cropped image");
%             app.ax_im=axes('Parent',app.fig_zstack,'Units','normalized');
%             app.ax_crop=axes('Parent',app.fig_zstack_zoom,'Units','normalized');
            app.FirstFrameZ_stackEditField.Value=30;
            app.LastFrameZ_stackEditField .Value=100;
            app.d_helper= defocustracker_helperfunctions;
            %app.ax_roi=axes('Parent',app.fig,'Units','normalized');
            app.debugmode=0;
            app.logger={};
            app.add_to_log('program started')
            app.MedianFilterEditField.Value=3;       % median filter to reduce thermal noise
            app.GaussFilterEditField.Value=0.0;
            app.SmoothingEditField.Value=0.2; 
            app.jobs.model={};
            app.jobs.path={};


        end

        % Button pushed function: LoadZstackDataButton
        function LoadZstackDataButtonPushed(app, event)
            if app.debugmode==1
            myfolder=fullfile(app.app_dir,'demo_data\zstack');
            else
            myfolder = uigetdir;
            end
            
            %myfolder = uigetdir;
            if myfolder~=0
                z_stack_imgs=dir(fullfile(myfolder, '**\*.tif'));
                folder={z_stack_imgs.folder};
                filenames={z_stack_imgs.name};
                app.zstack_fullpath=fullfile(folder, filenames);
                fold=folder(1);
                app.zstack_folder=fold{1};
                app.zstack_files=(filenames);
                app.roi_pos=[80,45,80,100];
                update_image(app,1)
                app.Slider.Limits=[1 numel(filenames) ];
                %app.roi = images.roi.Rectangle(app.ax_roi,'Position',[80,45,80,100]);
                %app.roi = drawrectangle('Position',[500,500,1000,1000],'StripeColor','r');
                app.TrainButton.Enable="on";
                app.Slider.Enable="on";
                app.FoundZDataEditField.Value=fold{1};
                app.add_to_log('zstack loaded')

            end

        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            value = round(app.Slider.Value);
            app.Slider.Value=value;
            update_image(app,value);
            app.CurrentFrameEditField.Value=value;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            try
                close(app.fig_zstack)
            end
            try
                close(app.fig_zstack_zoom)
            end
            delete(app)
            
        end

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            %myfolder='C:\test\230131\zstack';
            if app.debugmode==1
                myfolder=fullfile(app.app_dir,'demo_data\experiments');
            else
            myfolder = uigetdir;
            end
            if myfolder~=0
                data_imgs=dir(fullfile(myfolder, '**\*.tif'));
                folder={data_imgs.folder};
                unique_folder=unique(folder);           
                app.experiment_folders=unique_folder;
                app.FoundExperimtalDataTextArea.Value=unique_folder;
                app.ProcessButton.Enable="on";
            end
            app.experiment_base_folder=myfolder;
        end

        % Button pushed function: TrainButton
        function TrainButtonPushed(app, event)
            app.train();
            app.add_to_log('model trained')
            app.LoadDataButton.Enable="on";
        end

        % Button pushed function: ProcessButton
        function ProcessButtonPushed(app, event)
            promptagain=1;
            while promptagain
                prompt = {'Enter name of .mat file where data is saved:'};
                dlgtitle = 'filename. pls put.mat as file ending:';
                dims = [1 50];
                definput = {'data.mat',};
                answer = inputdlg(prompt,dlgtitle,dims,definput);
                app.save_filename=answer;
                
                if isempty(answer)
                    promptagain=0;
                else
                    wheretosave=fullfile(app.experiment_base_folder, app.save_filename{1});
                    if ~isfile(wheretosave)
                        promptagain=0;
                        app.process(app.model,app.experiment_folders);
                    end
                end
                
            end
        end

        % Value changed function: DemoModeCheckBox
        function DemoModeCheckBoxValueChanged(app, event)
            value = app.DemoModeCheckBox.Value;
            app.debugmode=value;
        end

        % Callback function
        function addtologButtonPushed(app, event)
            app.add_to_log('hello')
        end

        % Button pushed function: Plot3dTracksButton
        function Plot3dTracksButtonPushed(app, event)
            %disp(app.ListBox_Loaded.Value)
            if app.CloseOldFigureCheckBox.Value==1
                app.close_dtrackerfigure();
            end
            [~,idx] = ismember(app.ListBox_Processed.Value,app.ListBox_Processed.Items);            
            datatoplot=app.post_processed_data(idx);            
            figure('Name','3d_tracks')            
            for i=1: numel(datatoplot)                
                dtracker_show(datatoplot{i},'plot_3d_tracks');
                hold on;
            end
            
        end

        % Button pushed function: LoadDataFromWorkspaceButton
        function LoadDataFromWorkspaceButtonPushed(app, event)
            loc_data=evalin('base', app.NameofVariableEditField.Value);            
            app.loaded_data=loc_data;
            descriptor=evalin('base', app.NameofDescriptorEditField.Value);            
            %app.ListBox_Loaded.ItemsData=data;
            app.ListBox_Loaded.Items=descriptor;
        end

        % Button pushed function: PostProcessButton
        function PostProcessButtonPushed(app, event)
            app.postprocess();
            app.add_to_log('postprocessing data')
        end

        % Button pushed function: saveprocesseddatatoworkspaceButton
        function saveprocesseddatatoworkspaceButtonPushed(app, event)
            assignin("base", app.NameofnewvariableEditField.Value, app.post_processed_data);
            assignin("base", [app.NameofnewvariableEditField.Value 'descriptor'], app.ListBox_Processed.Items)

        end

        % Button pushed function: MovetoJobsButton
        function MovetoJobsButtonPushed(app, event)
            if ~isempty(app.model) && ~isempty(app.experiment_folders)
                app.jobs.model(end+1)={app.model};
                app.jobs.path(end+1)={app.experiment_folders};
                app.JobsListBox.Items{end+1}=[app.model.identifier app.experiment_base_folder];
            end

        end

        % Button pushed function: RemoveSelectedJobsButton
        function RemoveSelectedJobsButtonPushed(app, event)
            [~,idx] = ismember(app.JobsListBox.Value,app.JobsListBox.Items);
            app.jobs.model(idx)=[];
            app.jobs.path(idx)=[];
            app.JobsListBox.Items(idx)=[];


        end

        % Button pushed function: ProcessJobsButton
        function ProcessJobsButtonPushed(app, event)
            promptagain=1;
            while promptagain
                prompt = {'Enter name of .mat file where data is saved:'};
                dlgtitle = 'filename. pls put.mat as file ending:';
                dims = [1 50];
                definput = {'data.mat',};
                answer = inputdlg(prompt,dlgtitle,dims,definput);
                app.save_filename=answer;                
                if isempty(answer)
                    promptagain=0;
                else
                    wheretosave=fullfile(app.experiment_base_folder, app.save_filename{1});
                    if ~isfile(wheretosave)
                        promptagain=0;
                        app.process_jobs();
                    end
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.LeftPanel = uipanel(app.UIFigure);
            app.TabGroup = uitabgroup(app.LeftPanel);
            app.TrainProcessTab = uitab(app.TabGroup);
            app.TrainProcessTab.Title = 'Train&Process';
            app.LoadZstackDataButton = uibutton(app.TrainProcessTab, 'push');
            app.LoadZstackDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadZstackDataButtonPushed, true);
            app.LoadZstackDataButton.Text = 'Load Z stack Data';
            app.LoadDataButton = uibutton(app.TrainProcessTab, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Enable = 'off';
            app.LoadDataButton.Text = 'Load Data';
            app.TrainButton = uibutton(app.TrainProcessTab, 'push');
            app.TrainButton.ButtonPushedFcn = createCallbackFcn(app, @TrainButtonPushed, true);
            app.TrainButton.Enable = 'off';
            app.TrainButton.Text = 'Train';
            app.ProcessButton = uibutton(app.TrainProcessTab, 'push');
            app.ProcessButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessButtonPushed, true);
            app.ProcessButton.Enable = 'off';
            app.ProcessButton.Text = 'Process';
            app.MedianFilterCheckBox = uicheckbox(app.TrainProcessTab);
            app.MedianFilterCheckBox.Tooltip = {'Removes "stuck particles" in the experimental data frames'};
            app.MedianFilterCheckBox.Text = 'Median Filter';
            app.DemoModeCheckBox = uicheckbox(app.TrainProcessTab);
            app.DemoModeCheckBox.ValueChangedFcn = createCallbackFcn(app, @DemoModeCheckBoxValueChanged, true);
            app.DemoModeCheckBox.Text = 'DemoMode';
            app.SliderLabel = uilabel(app.TrainProcessTab);
            app.SliderLabel.Enable = 'off';
            app.SliderLabel.Text = 'Slider';
            app.Slider = uislider(app.TrainProcessTab);
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.MinorTicks = [];
            app.Slider.Enable = 'off';
            app.CurrentFrameEditFieldLabel = uilabel(app.TrainProcessTab);
            app.CurrentFrameEditFieldLabel.Text = 'CurrentFrame';
            app.CurrentFrameEditField = uieditfield(app.TrainProcessTab, 'numeric');
            app.FoundExperimtalDataTextAreaLabel = uilabel(app.TrainProcessTab);
            app.FoundExperimtalDataTextAreaLabel.Text = 'Found Experimtal Data';
            app.FoundExperimtalDataTextArea = uitextarea(app.TrainProcessTab);
            app.FirstFrameZ_stackEditFieldLabel = uilabel(app.TrainProcessTab);
            app.FirstFrameZ_stackEditFieldLabel.Text = 'First Frame Z_stack';
            app.FirstFrameZ_stackEditField = uieditfield(app.TrainProcessTab, 'numeric');
            app.LastFrameZ_stackEditFieldLabel = uilabel(app.TrainProcessTab);
            app.LastFrameZ_stackEditFieldLabel.Text = 'Last Frame Z_stack';
            app.LastFrameZ_stackEditField = uieditfield(app.TrainProcessTab, 'numeric');
            app.FoundZDataEditFieldLabel = uilabel(app.TrainProcessTab);
            app.FoundZDataEditFieldLabel.Text = 'Found Z Data';
            app.FoundZDataEditField = uieditfield(app.TrainProcessTab, 'text');
            app.GaussFilterEditFieldLabel = uilabel(app.TrainProcessTab);
            app.GaussFilterEditFieldLabel.Text = 'Gauss Filter';
            app.GaussFilterEditField = uieditfield(app.TrainProcessTab, 'numeric');
            app.MedianFilterEditFieldLabel = uilabel(app.TrainProcessTab);
            app.MedianFilterEditFieldLabel.Text = 'Median Filter';
            app.MedianFilterEditField = uieditfield(app.TrainProcessTab, 'numeric');
            app.SmoothingEditFieldLabel = uilabel(app.TrainProcessTab);
            app.SmoothingEditFieldLabel.Text = 'Smoothing';
            app.SmoothingEditField = uieditfield(app.TrainProcessTab, 'numeric');
            app.MovetoJobsButton = uibutton(app.TrainProcessTab, 'push');
            app.MovetoJobsButton.ButtonPushedFcn = createCallbackFcn(app, @MovetoJobsButtonPushed, true);
            app.MovetoJobsButton.Text = 'Move to Jobs';
            app.ProcessJobsButton = uibutton(app.TrainProcessTab, 'push');
            app.ProcessJobsButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessJobsButtonPushed, true);
            app.ProcessJobsButton.Text = 'Process Jobs';
            app.JobsListBoxLabel = uilabel(app.TrainProcessTab);
            app.JobsListBoxLabel.Text = 'Jobs';
            app.JobsListBox = uilistbox(app.TrainProcessTab);
            app.JobsListBox.Items = {};
            app.JobsListBox.Value = {};
            app.RemoveSelectedJobsButton = uibutton(app.TrainProcessTab, 'push');
            app.RemoveSelectedJobsButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveSelectedJobsButtonPushed, true);
            app.RemoveSelectedJobsButton.Text = 'Remove Selected Jobs';
            app.LoadPostprocessandViewdataTab = uitab(app.TabGroup);
            app.LoadPostprocessandViewdataTab.Title = 'Load, Postprocess and View data';
            app.ListBox_Loaded = uilistbox(app.LoadPostprocessandViewdataTab);
            app.ListBox_Loaded.Multiselect = 'on';
            app.ListBox_Loaded.Value = {'Item 1'};
            app.Plot3dTracksButton = uibutton(app.LoadPostprocessandViewdataTab, 'push');
            app.Plot3dTracksButton.ButtonPushedFcn = createCallbackFcn(app, @Plot3dTracksButtonPushed, true);
            app.Plot3dTracksButton.Text = 'Plot 3d Tracks';
            app.LoadDataFromWorkspaceButton = uibutton(app.LoadPostprocessandViewdataTab, 'push');
            app.LoadDataFromWorkspaceButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataFromWorkspaceButtonPushed, true);
            app.LoadDataFromWorkspaceButton.Text = 'Load Data From Workspace';
            app.NameofVariableEditFieldLabel = uilabel(app.LoadPostprocessandViewdataTab);
            app.NameofVariableEditFieldLabel.Text = 'Name of Variable';
            app.NameofVariableEditField = uieditfield(app.LoadPostprocessandViewdataTab, 'text');
            app.NameofVariableEditField.Value = 'data';
            app.NameofDescriptorEditFieldLabel = uilabel(app.LoadPostprocessandViewdataTab);
            app.NameofDescriptorEditFieldLabel.Text = 'Name of Descriptor';
            app.NameofDescriptorEditField = uieditfield(app.LoadPostprocessandViewdataTab, 'text');
            app.NameofDescriptorEditField.Value = 'descriptor';
            app.PostProcessButton = uibutton(app.LoadPostprocessandViewdataTab, 'push');
            app.PostProcessButton.ButtonPushedFcn = createCallbackFcn(app, @PostProcessButtonPushed, true);
            app.PostProcessButton.Text = 'PostProcess';
            app.TrackingCheckBox = uicheckbox(app.LoadPostprocessandViewdataTab);
            app.TrackingCheckBox.Text = 'Tracking';
            app.TrackingCheckBox.Value = true;
            app.MinCMCheckBox = uicheckbox(app.LoadPostprocessandViewdataTab);
            app.MinCMCheckBox.Text = 'Min CM';
            app.MinCMCheckBox.Value = true;
            app.MinCMEditFieldLabel = uilabel(app.LoadPostprocessandViewdataTab);
            app.MinCMEditFieldLabel.Text = 'Min CM';
            app.MinCMEditField = uieditfield(app.LoadPostprocessandViewdataTab, 'numeric');
            app.MinCMEditField.Value = 0.9;
            app.DXmaxEditFieldLabel = uilabel(app.LoadPostprocessandViewdataTab);
            app.DXmaxEditFieldLabel.Text = 'DX max';
            app.DXmaxEditField = uieditfield(app.LoadPostprocessandViewdataTab, 'numeric');
            app.DXmaxEditField.Value = 10;
            app.DXminEditFieldLabel = uilabel(app.LoadPostprocessandViewdataTab);
            app.DXminEditFieldLabel.Text = 'DX min';
            app.DXminEditField = uieditfield(app.LoadPostprocessandViewdataTab, 'numeric');
            app.DXminEditField.Value = -10;
            app.DYminEditFieldLabel = uilabel(app.LoadPostprocessandViewdataTab);
            app.DYminEditFieldLabel.Text = 'DY min';
            app.DYminEditField = uieditfield(app.LoadPostprocessandViewdataTab, 'numeric');
            app.DYminEditField.Value = -100;
            app.DYmaxEditFieldLabel = uilabel(app.LoadPostprocessandViewdataTab);
            app.DYmaxEditFieldLabel.Text = 'DY max';
            app.DYmaxEditField = uieditfield(app.LoadPostprocessandViewdataTab, 'numeric');
            app.DYmaxEditField.Value = 100;
            app.DZmaxEditFieldLabel = uilabel(app.LoadPostprocessandViewdataTab);
            app.DZmaxEditFieldLabel.Text = 'DZ max';
            app.DZmaxEditField = uieditfield(app.LoadPostprocessandViewdataTab, 'numeric');
            app.DZmaxEditField.Value = 0.1;
            app.DZminEditFieldLabel = uilabel(app.LoadPostprocessandViewdataTab);
            app.DZminEditFieldLabel.Text = 'DZ min';
            app.DZminEditField = uieditfield(app.LoadPostprocessandViewdataTab, 'numeric');
            app.DZminEditField.Value = -0.1;
            app.CloseOldFigureCheckBox = uicheckbox(app.LoadPostprocessandViewdataTab);
            app.CloseOldFigureCheckBox.Text = 'Close Old Figure';
            app.saveprocesseddatatoworkspaceButton = uibutton(app.LoadPostprocessandViewdataTab, 'push');
            app.saveprocesseddatatoworkspaceButton.ButtonPushedFcn = createCallbackFcn(app, @saveprocesseddatatoworkspaceButtonPushed, true);
            app.saveprocesseddatatoworkspaceButton.Text = {'save processed data '; 'to workspace'};
            app.NameofnewvariableEditFieldLabel = uilabel(app.LoadPostprocessandViewdataTab);
            app.NameofnewvariableEditFieldLabel.Text = 'Name of new variable';
            app.NameofnewvariableEditField = uieditfield(app.LoadPostprocessandViewdataTab, 'text');
            app.ListBox_Processed = uilistbox(app.LoadPostprocessandViewdataTab);
            app.ListBox_Processed.Multiselect = 'on';
            app.ListBox_Processed.Value = {'Item 1'};
            app.NameofnewdescriptorEditFieldLabel = uilabel(app.LoadPostprocessandViewdataTab);
            app.NameofnewdescriptorEditFieldLabel.Text = 'Name of new descriptor';
            app.NameofnewdescriptorEditField = uieditfield(app.LoadPostprocessandViewdataTab, 'text');
            app.ScaleCheckBox = uicheckbox(app.LoadPostprocessandViewdataTab);
            app.ScaleCheckBox.Text = 'Scale';
            app.ScaleCheckBox.Value = true;
            app.ScaleXEditFieldLabel = uilabel(app.LoadPostprocessandViewdataTab);
            app.ScaleXEditFieldLabel.Text = 'Scale X';
            app.ScaleXEditField = uieditfield(app.LoadPostprocessandViewdataTab, 'numeric');
            app.ScaleXEditField.Value = -0.1;
            app.ScaleYEditFieldLabel = uilabel(app.LoadPostprocessandViewdataTab);
            app.ScaleYEditFieldLabel.Text = 'Scale Y';
            app.ScaleYEditField = uieditfield(app.LoadPostprocessandViewdataTab, 'numeric');
            app.ScaleYEditField.Value = -0.1;
            app.ScaleZEditFieldLabel = uilabel(app.LoadPostprocessandViewdataTab);
            app.ScaleZEditFieldLabel.Text = 'Scale Z';
            app.ScaleZEditField = uieditfield(app.LoadPostprocessandViewdataTab, 'numeric');
            app.ScaleZEditField.Value = -0.1;
            app.ScaleUnitEditFieldLabel = uilabel(app.LoadPostprocessandViewdataTab);
            app.ScaleUnitEditFieldLabel.Text = 'Scale Unit';
            app.ScaleUnitEditField = uieditfield(app.LoadPostprocessandViewdataTab, 'text');
            app.ScaleUnitEditField.Value = 'um';
            app.ScaledtEditFieldLabel = uilabel(app.LoadPostprocessandViewdataTab);
            app.ScaledtEditFieldLabel.Text = 'Scale dt';
            app.ScaledtEditField = uieditfield(app.LoadPostprocessandViewdataTab, 'numeric');
            app.ScaledtEditField.Value = 1;
            app.LogTab = uitab(app.TabGroup);
            app.LogTab.Title = 'Log';
            app.LogTextAreaLabel = uilabel(app.LogTab);
            app.LogTextAreaLabel.Text = 'Log';
            app.LogTextArea = uitextarea(app.LogTab);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = defocustracker_ui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end